classdef RootFinder < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        RootFinderLabel               matlab.ui.control.Label
        InputPanel                    matlab.ui.container.Panel
        InsertequationEditFieldLabel  matlab.ui.control.Label
        InsertequationEditField       matlab.ui.control.EditField
        ChoosemethodDropDownLabel     matlab.ui.control.Label
        ChoosemethodDropDown          matlab.ui.control.DropDown
        ofIterationsEditFieldLabel    matlab.ui.control.Label
        ofIterationsEditField         matlab.ui.control.NumericEditField
        PrecisionEditFieldLabel       matlab.ui.control.Label
        PrecisionEditField            matlab.ui.control.NumericEditField
        InitialPointEditFieldLabel    matlab.ui.control.Label
        InitialPointEditField         matlab.ui.control.NumericEditField
        SolveButton                   matlab.ui.control.Button
        SecondPointEditFieldLabel     matlab.ui.control.Label
        SecondPointEditField          matlab.ui.control.NumericEditField
        forclosedmethodsLabel         matlab.ui.control.Label
        forfixediterationLabel        matlab.ui.control.Label
        MagicFormulaEditFieldLabel    matlab.ui.control.Label
        MagicFormulaEditField         matlab.ui.control.EditField
        OutputPanel                   matlab.ui.container.Panel
        UIAxes2                       matlab.ui.control.UIAxes
        UITable                       matlab.ui.control.Table
    end


    methods (Access = private)
    
        
    end


    methods (Access = private)

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)
            method = app.ChoosemethodDropDown.Value;
            fx = app.InsertequationEditField.Value;
            n = app.ofIterationsEditField.Value;
            p = app.PrecisionEditField.Value;
            x1 = app.InitialPointEditField.Value;
            x2= app.SecondPointEditField.Value;
            method=method-48;
            switch(method)
                case 1 %Default --> Newton Method
                    
                  [it,r,xold,er,size] = NewtonRaphson_iteration(fx,x1,n,p);
                   I=transpose(it);
                   R=transpose(r);
                   E=transpose(er);
                   t = table(I(1:size),R(1:size),E(1:size));
                   app.UITable.Data=t;
                   f=str2func(['@(x)',fx]);
                   n1=x1+2;
                   n2=x1-2;
                   n = n2:0.00001:n1;
                   y=feval(f,n);
                   app.UIAxes2.cla;
                   for k=1:size
                   plot(app.UIAxes2,n,y,'r-');
                   hold(app.UIAxes2, 'on')
                   plot(app.UIAxes2,[xold(k) xold(k)],[0 feval(f,xold(k))],'k--')
                   hold(app.UIAxes2, 'on')
                   plot(app.UIAxes2,[xold(k) r(k)],[feval(f,xold(k)) 0],'k-')
                   hold(app.UIAxes2, 'on')
                   end
                case 2 %Bisection Method
                  f = str2func(['@(x)',fx]);
                  [iterations,xl,xu,xr,error] = bisection(f,x1,x2,p,n);
                  XR = transpose(xr);
                  ERROR = transpose(error);
                  I = zeros(1,iterations);
                  for i = 1:iterations
                      I(i) = i;
                  end
                  IT = transpose(I);
                  t = table(IT(1:iterations),XR(1:iterations),ERROR(1:iterations));
                  app.UITable.Data = t;
                  
                   f=str2func(['@(x)',fx]);
                   n1=x1-1;
                   n2=x2+1;
                   n = n1:0.0001:n2;
                   y=feval(f,n);    
                   plot(app.UIAxes2,n,y,'r-');
                   for i=1:iterations
                   hold(app.UIAxes2, 'on')
                   plot(app.UIAxes2,[xl(i) xl(i)],[feval(f,xl(i))-10 feval(f,xl(i))+10],'b-');
                   hold(app.UIAxes2, 'on')
                   plot(app.UIAxes2,[xu(i) xu(i)],[feval(f,xu(i))-10 feval(f,xu(i))]+10,'g-');
                   hold(app.UIAxes2, 'on')
                   end
                   
                case 4 %fixed point
                  gx = app.MagicFormulaEditField.Value;
                  [it,size,xold,xnew,er] = FixedPoint_iteration(gx,n,p,x1);
                  XN=transpose(xnew);
                  E=transpose(er);
                  I=transpose(it);
                  t = table(I(1:size),XN(1:size),E(1:size));
                  app.UITable.Data=t;
                  n=(x1-10):0.000001:(x1+10);
                  g=str2func(['@(x)',gx]);
                  z=feval(g,n);
                  y=n;
                  app.UIAxes2.cla;
                  for k=1:size
                      plot(app.UIAxes2,n,z,'b-');
                      hold(app.UIAxes2, 'on')
                      plot(app.UIAxes2,n,y,'r-');
                      hold(app.UIAxes2, 'on')
                      plot(app.UIAxes2,[xold(k) xold(k)],[xold(k) feval(g,xold(k))],'k-')
                      plot(app.UIAxes2,[xold(k) xnew(k)],[xold(k) feval(g,xnew(k))],'k--')
                  end
                case 3 %regula falsi
                  f = str2func(['@(x)',fx]);
                  [iterations,xl,xu,xr,error] = false_position(f,x1,x2,p,n);
                  XR = transpose(xr);
                  ERROR = transpose(error);
                  I = zeros(1,iterations);
                  for i = 1:iterations
                      I(i) = i;
                  end
                  IT = transpose(I);
                  t = table(IT(1:iterations),XR(1:iterations),ERROR(1:iterations));
                  app.UITable.Data = t;
                  
                  f=str2func(['@(x)',fx]);
                   n1=x1-1;
                   n2=x2+1;
                   n = n1:0.000001:n2;
                   y=feval(f,n);    
                   plot(app.UIAxes2,n,y,'r-');
                   for i=1:iterations
                   hold(app.UIAxes2, 'on')
                   plot(app.UIAxes2,[xl(i) xl(i)],[feval(f,xl(i))-10 feval(f,xl(i))+10],'b-');
                   hold(app.UIAxes2, 'on')
                   plot(app.UIAxes2,[xu(i) xu(i)],[feval(f,xu(i))-10 feval(f,xu(i))+10],'g-');
                   hold(app.UIAxes2, 'on')
                   end
                  
                case 5 % newton
                   [it,r,xold,er,size] = NewtonRaphson_iteration(fx,x1,n,p);
                   I=transpose(it);
                   R=transpose(r);
                   E=transpose(er);
                   t = table(I(1:size),R(1:size),E(1:size));
                   app.UITable.Data=t;
                   f=str2func(['@(x)',fx]);
                   n1=x1+2;
                   n2=x1-2;
                   n = n2:0.00001:n1;
                   y=feval(f,n);
                   app.UIAxes2.cla;
                   for k=1:size
                   plot(app.UIAxes2,n,y,'r-');
                   hold(app.UIAxes2, 'on')
                   plot(app.UIAxes2,[xold(k) xold(k)],[0 feval(f,xold(k))],'k--')
                   hold(app.UIAxes2, 'on')
                   plot(app.UIAxes2,[xold(k) r(k)],[feval(f,xold(k)) 0],'k-')
                   hold(app.UIAxes2, 'on')
                   end
                case 6 %secant     
                    x2=app.SecondPointEditField.Value;
                   [it, r, er,size,point1,point2] = Secant(fx,x1,n,p,x2);
                   I=transpose(it);
                   R=transpose(r);
                   E=transpose(er);
                   t = table(I(1:size),R(1:size),E(1:size));
                   app.UITable.Data=t;
                   f=str2func(['@(x)',fx]);
                   n1=x1+4;
                   n2=n1-4;
                   n = n2:0.00001:n1;
                   y=feval(f,n);
                   for k=1:size    
                   plot(app.UIAxes2,n,y,'r-');
                   hold(app.UIAxes2, 'on')
                   end
                otherwise
                  fprintf("error");
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.RootFinderLabel = uilabel(app.UIFigure);
            app.RootFinderLabel.Text = 'Root Finder';
            app.InputPanel = uipanel(app.UIFigure);
            app.InputPanel.Title = 'Input';
            app.InsertequationEditFieldLabel = uilabel(app.InputPanel);
            app.InsertequationEditFieldLabel.Text = 'Insert equation';
            app.InsertequationEditField = uieditfield(app.InputPanel, 'text');
            app.ChoosemethodDropDownLabel = uilabel(app.InputPanel);
            app.ChoosemethodDropDownLabel.Text = 'Choose method';
            app.ChoosemethodDropDown = uidropdown(app.InputPanel);
            app.ChoosemethodDropDown.Items = {'Default', 'Bisection', 'Regula-falsi', 'Fixed Point Iteration', 'Newton Iteration', 'Secant'};
            app.ChoosemethodDropDown.ItemsData = {'1', '2', '3', '4', '5', '6'};
            app.ChoosemethodDropDown.Value = '1';
            app.ofIterationsEditFieldLabel = uilabel(app.InputPanel);
            app.ofIterationsEditFieldLabel.Text = '# of Iterations';
            app.ofIterationsEditField = uieditfield(app.InputPanel, 'numeric');
            app.ofIterationsEditField.Value = 50;
            app.PrecisionEditFieldLabel = uilabel(app.InputPanel);
            app.PrecisionEditFieldLabel.Text = 'Precision';
            app.PrecisionEditField = uieditfield(app.InputPanel, 'numeric');
            app.PrecisionEditField.Value = 1e-05;
            app.InitialPointEditFieldLabel = uilabel(app.InputPanel);
            app.InitialPointEditFieldLabel.Text = 'Initial Point';
            app.InitialPointEditField = uieditfield(app.InputPanel, 'numeric');
            app.SolveButton = uibutton(app.InputPanel, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Text = 'Solve';
            app.SecondPointEditFieldLabel = uilabel(app.InputPanel);
            app.SecondPointEditFieldLabel.Text = 'Second Point';
            app.SecondPointEditField = uieditfield(app.InputPanel, 'numeric');
            app.forclosedmethodsLabel = uilabel(app.InputPanel);
            app.forclosedmethodsLabel.Text = 'for closed methods';
            app.forfixediterationLabel = uilabel(app.InputPanel);
            app.forfixediterationLabel.Text = 'for fixed iteration';
            app.MagicFormulaEditFieldLabel = uilabel(app.InputPanel);
            app.MagicFormulaEditFieldLabel.Text = 'MagicFormula';
            app.MagicFormulaEditField = uieditfield(app.InputPanel, 'text');
            app.OutputPanel = uipanel(app.UIFigure);
            app.OutputPanel.Title = 'Output';
            app.UIAxes2 = uiaxes(app.OutputPanel);
            title(app.UIAxes2, 'Graphical representation')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UITable = uitable(app.OutputPanel);
            app.UITable.ColumnName = {'iteration'; 'root'; 'approximate error'};
            app.UITable.RowName = {};
        end
    end

    methods (Access = public)

        % Construct app
        function app = RootFinder

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end